classdef aplikasi_fibonaci < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        MulaiButton               matlab.ui.control.Button
        AngkaKeduaEditField       matlab.ui.control.NumericEditField
        AngkaKeduaLabel           matlab.ui.control.Label
        AngkaPertamaEditField     matlab.ui.control.NumericEditField
        AngkaPertamaLabel         matlab.ui.control.Label
        InputFibonacciLabel       matlab.ui.control.Label
        JumlahDeretEditField      matlab.ui.control.NumericEditField
        JumlahDeretLabel          matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        OutputFibonacciLabel      matlab.ui.control.Label
        HasilPerhitunganTextArea  matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MulaiButton
        function MulaiButtonPushed(app, event)
            jumlah_deret = app.JumlahDeretEditField.Value;
            bilangan_1 = app.AngkaPertamaEditField.Value;
            bilangan_2 = app.AngkaKeduaEditField.Value;

            if isempty(jumlah_deret) || isempty(bilangan_1) || isempty(bilangan_2)
                app.HasilPerhitunganTextArea.Value = {'Input Tidak Boleh Kosong'};
                return;
            end

            if jumlah_deret <= 0
                app.HasilPerhitunganTextArea.Value = {'Jumlah Deret Tidak Boleh Kurang dari 1'};
                return;
            end

            fibo(1) = bilangan_1;
            if jumlah_deret > 1
                fibo(2) = bilangan_2;
            end
            for i = 3:jumlah_deret
                fibo(i) = fibo(i-1) + fibo(i-2);
            end

            hasilStr = string(fibo);
            app.HasilPerhitunganTextArea.Value = cellstr(hasilStr(:));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {385, 385};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {321, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.JumlahDeretLabel = uilabel(app.LeftPanel);
            app.JumlahDeretLabel.Text = 'Jumlah Deret :';
            app.JumlahDeretEditField = uieditfield(app.LeftPanel, 'numeric');
            app.JumlahDeretEditField.AllowEmpty = 'on';
            app.JumlahDeretEditField.Placeholder = 'Masukan Jumlah Deret';
            app.JumlahDeretEditField.Value = [];
            app.InputFibonacciLabel = uilabel(app.LeftPanel);
            app.InputFibonacciLabel.Text = 'Input Fibonacci ';
            app.AngkaPertamaLabel = uilabel(app.LeftPanel);
            app.AngkaPertamaLabel.Text = 'Angka Pertama :';
            app.AngkaPertamaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AngkaPertamaEditField.AllowEmpty = 'on';
            app.AngkaPertamaEditField.Placeholder = 'Masukan Angka Pertama';
            app.AngkaPertamaEditField.Value = [];
            app.AngkaKeduaLabel = uilabel(app.LeftPanel);
            app.AngkaKeduaLabel.Text = 'Angka Kedua :';
            app.AngkaKeduaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AngkaKeduaEditField.AllowEmpty = 'on';
            app.AngkaKeduaEditField.Placeholder = 'Masukan Angka Kedua';
            app.AngkaKeduaEditField.Value = [];
            app.MulaiButton = uibutton(app.LeftPanel, 'push');
            app.MulaiButton.ButtonPushedFcn = createCallbackFcn(app, @MulaiButtonPushed, true);
            app.MulaiButton.Text = 'Mulai';
            app.RightPanel = uipanel(app.GridLayout);
            app.HasilPerhitunganTextArea = uitextarea(app.RightPanel);
            app.HasilPerhitunganTextArea.Editable = 'off';
            app.HasilPerhitunganTextArea.Placeholder = 'Hasil Perhitungan';
            app.OutputFibonacciLabel = uilabel(app.RightPanel);
            app.OutputFibonacciLabel.Text = 'Output Fibonacci ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aplikasi_fibonaci

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end